classdef MetaDataLEAP_03 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        SpecimenPanel                   matlab.ui.container.Panel
        notesTextArea                   matlab.ui.control.TextArea
        notesTextAreaLabel              matlab.ui.control.Label
        compositionNominalEditField     matlab.ui.control.EditField
        compositionNominalEditFieldLabel  matlab.ui.control.Label
        materialTypeEditField           matlab.ui.control.EditField
        materialTypeEditFieldLabel      matlab.ui.control.Label
        uniqueIdentifierEditField       matlab.ui.control.EditField
        uniqueIdentifierEditFieldLabel  matlab.ui.control.Label
        descriptionEditField            matlab.ui.control.EditField
        descriptionEditFieldLabel       matlab.ui.control.Label
        nameEditField                   matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
        InstrumentPanel                 matlab.ui.container.Panel
        laserIncidenceDirectionEditField_3  matlab.ui.control.NumericEditField
        laserIncidenceDirectionEditField_2  matlab.ui.control.NumericEditField
        detectorSizeEditField_2         matlab.ui.control.NumericEditField
        laserIncidenceDirectionEditField  matlab.ui.control.NumericEditField
        laserIncidenceDirectionEditFieldLabel  matlab.ui.control.Label
        detectorSizeEditField           matlab.ui.control.NumericEditField
        detectorSizeEditFieldLabel      matlab.ui.control.Label
        detectorResolutionEditField     matlab.ui.control.NumericEditField
        detectorResolutionEditFieldLabel  matlab.ui.control.Label
        detectorReadoutEditField        matlab.ui.control.EditField
        detectorReadoutEditFieldLabel   matlab.ui.control.Label
        apertureUniqueIdentifierEditField  matlab.ui.control.EditField
        apertureUniqueIdentifierEditFieldLabel  matlab.ui.control.Label
        apertureTypeEditField           matlab.ui.control.EditField
        apertureTypeEditFieldLabel      matlab.ui.control.Label
        detectionEfficiencyEditField    matlab.ui.control.NumericEditField
        detectionEfficiencyEditFieldLabel  matlab.ui.control.Label
        t0EditField                     matlab.ui.control.NumericEditField
        t0EditFieldLabel                matlab.ui.control.Label
        flightPathLengthEditField       matlab.ui.control.NumericEditField
        flightPathLengthEditFieldLabel  matlab.ui.control.Label
        lastCalibrationDateDatePicker   matlab.ui.control.DatePicker
        lastCalibrationDateDatePickerLabel  matlab.ui.control.Label
        manufacturerEditField           matlab.ui.control.EditField
        manufacturerEditFieldLabel      matlab.ui.control.Label
        acquisitionDeviceEditField      matlab.ui.control.EditField
        acquisitionDeviceEditFieldLabel  matlab.ui.control.Label
        typeEditField                   matlab.ui.control.EditField
        typeEditFieldLabel              matlab.ui.control.Label
        familyEditField                 matlab.ui.control.EditField
        familyEditFieldLabel            matlab.ui.control.Label
        ExperimentPanel                 matlab.ui.container.Panel
        latitudeEditField               matlab.ui.control.NumericEditField
        latitudeEditFieldLabel          matlab.ui.control.Label
        longitudeEditField              matlab.ui.control.NumericEditField
        longitudeEditFieldLabel         matlab.ui.control.Label
        institutionEditField            matlab.ui.control.EditField
        institutionEditFieldLabel       matlab.ui.control.Label
        ProjectPanel                    matlab.ui.container.Panel
        endDateDatePicker               matlab.ui.control.DatePicker
        endDateDatePickerLabel          matlab.ui.control.Label
        startDateDatePicker             matlab.ui.control.DatePicker
        startDateDatePickerLabel        matlab.ui.control.Label
        dataSharingLicenseEditField     matlab.ui.control.EditField
        dataSharingLicenseEditFieldLabel  matlab.ui.control.Label
        confidentialityDropDown         matlab.ui.control.DropDown
        confidentialityDropDownLabel    matlab.ui.control.Label
        contactPersonEditField          matlab.ui.control.EditField
        contactPersonEditFieldLabel     matlab.ui.control.Label
        identifierEditField             matlab.ui.control.EditField
        identifierEditFieldLabel        matlab.ui.control.Label
        LoadmetadatafromWorkspacePanel  matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        LoadButton                      matlab.ui.control.Button
        WSMetaVariableEditField         matlab.ui.control.EditField
        WSMetaVariableEditFieldLabel    matlab.ui.control.Label
        APTDataTab                      matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        SavePanel                       matlab.ui.container.Panel
        WSStoreNameEditField            matlab.ui.control.EditField
        WSStoreNameEditFieldLabel       matlab.ui.control.Label
        SaveToWSButton                  matlab.ui.control.Button
        ExperimentPanel_2               matlab.ui.container.Panel
        endTimeEditField                matlab.ui.control.EditField
        endTimeEditFieldLabel           matlab.ui.control.Label
        startTimeEditField              matlab.ui.control.EditField
        startTimeEditFieldLabel         matlab.ui.control.Label
        endTimeZoneEditField            matlab.ui.control.EditField
        endTimeZoneEditFieldLabel       matlab.ui.control.Label
        publicationDOIEditField         matlab.ui.control.EditField
        publicationDOIEditFieldLabel    matlab.ui.control.Label
        dataEmbargoPeriodEndDateDatePicker  matlab.ui.control.DatePicker
        dataEmbargoPeriodEndDateDatePickerLabel  matlab.ui.control.Label
        notesTextArea_2                 matlab.ui.control.TextArea
        notesTextArea_2Label            matlab.ui.control.Label
        resultDropDown                  matlab.ui.control.DropDown
        resultDropDownLabel             matlab.ui.control.Label
        statusDropDown                  matlab.ui.control.DropDown
        statusDropDownLabel             matlab.ui.control.Label
        uniqueIdentifierEditField_2     matlab.ui.control.EditField
        uniqueIdentifierEditField_2Label  matlab.ui.control.Label
        endDateTimeDatePicker           matlab.ui.control.DatePicker
        endDateTimeDatePickerLabel      matlab.ui.control.Label
        beginDateTimeDatePicker         matlab.ui.control.DatePicker
        beginDateTimeDatePickerLabel    matlab.ui.control.Label
        beginTimeZoneEditField          matlab.ui.control.EditField
        beginTimeZoneEditFieldLabel     matlab.ui.control.Label
        instrumentOperatorEditField     matlab.ui.control.EditField
        instrumentOperatorEditFieldLabel  matlab.ui.control.Label
        typeEditField_2                 matlab.ui.control.EditField
        typeEditField_2Label            matlab.ui.control.Label
        SpecimenPreparationPanel        matlab.ui.container.Panel
        electropolishingTemperatureEditField  matlab.ui.control.NumericEditField
        electropolishingTemperatureEditFieldLabel  matlab.ui.control.Label
        electroPolishingVoltageEditField  matlab.ui.control.NumericEditField
        electroPolishingVoltageEditFieldLabel  matlab.ui.control.Label
        electroPolishingAgentEditField  matlab.ui.control.EditField
        electroPolishingAgentEditFieldLabel  matlab.ui.control.Label
        finalIonBeamEnergyEditField     matlab.ui.control.NumericEditField
        finalIonBeamEnergyEditFieldLabel  matlab.ui.control.Label
        instrumentEditField             matlab.ui.control.EditField
        instrumentEditFieldLabel        matlab.ui.control.Label
        methodDropDown                  matlab.ui.control.DropDown
        methodDropDownLabel             matlab.ui.control.Label
        APTExperimentPanel              matlab.ui.container.Panel
        specimenStagePositionEditField_3  matlab.ui.control.NumericEditField
        specimenStagePositionEditField_2  matlab.ui.control.NumericEditField
        laserPositionEditField_3        matlab.ui.control.NumericEditField
        laserPositionEditField_2        matlab.ui.control.NumericEditField
        specimenStagePositionEditField  matlab.ui.control.NumericEditField
        specimenStagePositionEditFieldLabel  matlab.ui.control.Label
        pulsePeriodEditField            matlab.ui.control.NumericEditField
        pulsePeriodEditFieldLabel       matlab.ui.control.Label
        laserPositionEditField          matlab.ui.control.NumericEditField
        laserPositionEditFieldLabel     matlab.ui.control.Label
        laserPulseEnergyEditField       matlab.ui.control.NumericEditField
        laserPulseEnergyEditFieldLabel  matlab.ui.control.Label
        pulseFractionEditField          matlab.ui.control.NumericEditField
        pulseFractionEditFieldLabel     matlab.ui.control.Label
        measurementChamberPressureEditField  matlab.ui.control.NumericEditField
        measurementChamberPressureEditFieldLabel  matlab.ui.control.Label
        specimenTemperatureNominalEditField  matlab.ui.control.NumericEditField
        specimenTemperatureNominalEditFieldLabel  matlab.ui.control.Label
        specimenTemperatureActualEditField  matlab.ui.control.NumericEditField
        specimenTemperatureActualEditFieldLabel  matlab.ui.control.Label
        pulseTypeDropDown               matlab.ui.control.DropDown
        pulseTypeDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        MetaWS % Description
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load meta data from workspace
            app.MetaWS = evalin("base",app.WSMetaVariableEditField.Value);
            
            
            % General -> Project
                % string
                    projectIdentifier = app.MetaWS(contains(app.MetaWS(:,1),'/project/identifier'),2);
                    if ~isempty(projectIdentifier{1,1}) == 1
                        app.identifierEditField.Value = char(projectIdentifier{1,1});
                    end
                    projectContactPerson = app.MetaWS(contains(app.MetaWS(:,1),'/project/contactPerson'),2);
                    if ~isempty(projectContactPerson{1,1}) == 1
                        app.contactPersonEditField.Value = char(projectContactPerson{1,1});
                    end
                    projectDataSharingLicense = app.MetaWS(contains(app.MetaWS(:,1),'/project/dataSharingLicense'),2);
                    if ~isempty(projectDataSharingLicense{1,1}) == 1
                        app.dataSharingLicenseEditField.Value = char(projectDataSharingLicense{1,1});
                    end 
                % categorical
                    value = app.MetaWS(contains(app.MetaWS(:,1),'/project/confidentiality'),2);
                    if isempty(value) == 1
                        app.confidentialityDropDown.Value = 'tbd';
                    else
                        app.confidentialityDropDown.Value = char(value{1});
                    end
                    
                % Date data
                    startDate = app.MetaWS(contains(app.MetaWS(:,1),'/project/startDate'),2);
                    if isempty(startDate{1,1}) == 0
                        app.startDateDatePicker.Value = datetime(char(startDate{1,1}), 'Format', 'yyyy-MM-dd');
                    end
                    endDate = app.MetaWS(contains(app.MetaWS(:,1),'/project/endDate'),2);
                    if isempty(endDate{1,1}) == 0
                        app.endDateDatePicker.Value = datetime(char(endDate{1,1}), 'Format', 'yyyy-MM-dd');
                    end
            % General -> Specimen
                  % string
                    experimentName = app.MetaWS(contains(app.MetaWS(:,1),'/specimen/name'),2);
                    if ~isempty(experimentName{1,1}) == 1
                        app.nameEditField.Value = char(experimentName{1,1});
                    end
                    experimentDescription = app.MetaWS(contains(app.MetaWS(:,1),'/specimen/description'),2);
                    if ~isempty(experimentDescription{1,1}) == 1
                        app.descriptionEditField.Value = char(experimentDescription{1,1});
                    end
                    experimentUniqueIdentifier = app.MetaWS(contains(app.MetaWS(:,1),'/specimen/uniqueIdentifier'),2);
                    if ~isempty(experimentUniqueIdentifier{1,1}) == 1
                        app.uniqueIdentifierEditField.Value = char(experimentUniqueIdentifier{1,1});
                    end
                    experimentMaterialType = app.MetaWS(contains(app.MetaWS(:,1),'/specimen/materialType'),2);
                    if ~isempty(experimentMaterialType{1,1}) == 1
                        app.materialTypeEditField.Value = char(experimentMaterialType{1,1});
                    end
                    experimentCompositionNominal = app.MetaWS(contains(app.MetaWS(:,1),'/specimen/compositionNominal'),2);
                    if ~isempty(experimentCompositionNominal{1,1}) == 1
                        app.compositionNominalEditField.Value = char(experimentCompositionNominal{1,1});
                    end
                    experimentNotes = app.MetaWS(contains(app.MetaWS(:,1),'/specimen/notes'),2);
                    if ~isempty(experimentNotes{1,1}) == 1
                        app.notesTextArea.Value = char(experimentNotes{1,1});
                    end
              
              % General -> Experiment
                  % string
                    experimentInstitution = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/institution'),2);
                    if ~isempty(experimentInstitution{1,1}) == 1
                        app.institutionEditField.Value = char(experimentInstitution{1,1});
                    end
                    % numeric  
                    experimentLongitude = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/longitude'),2);
                    if ~isempty(experimentLongitude{1,1}) == 1
                        app.longitudeEditField.Value = experimentLongitude{1,1};
                    end
                    experimentLatitude = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/latitude'),2);
                    if ~isempty(experimentLatitude{1,1}) == 1
                        app.latitudeEditField.Value = experimentLatitude{1,1};
                    end
                 
             % General -> Instrument
                  % string
                    instrumentFamily = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/family'),2);
                    if ~isempty(instrumentFamily{1,1}) == 1
                        app.familyEditField.Value = char(instrumentFamily{1,1});
                    end
                    instrumentType = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/type'),2);
                    if ~isempty(instrumentType{1,1}) == 1
                        app.typeEditField.Value = char(instrumentType{1,1});
                    end
                    instrumentAcquisitionDevice = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/acquisitionDevice'),2);
                    if ~isempty(instrumentAcquisitionDevice{1,1}) == 1
                        app.acquisitionDeviceEditField.Value = char(instrumentAcquisitionDevice{1,1});
                    end
                    instrumentManufacturer = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/manufacturer'),2);
                    if ~isempty(instrumentManufacturer{1,1}) == 1
                        app.manufacturerEditField.Value = char(instrumentManufacturer{1,1});
                    end
                    instrumentApertureType = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/apertureType'),2);
                    if ~isempty(instrumentApertureType{1,1}) == 1
                        app.apertureTypeEditField.Value = char(instrumentApertureType{1,1});
                    end
                    instrumentApertureUniqueIdentifier = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/apertureUniqueIdentifier'),2);
                    if ~isempty(instrumentApertureUniqueIdentifier{1,1}) == 1
                        app.apertureUniqueIdentifierEditField.Value = char(instrumentApertureUniqueIdentifier{1,1});
                    end
                    instrumentDetectorReadout = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/detectorReadout'),2);
                    if ~isempty(instrumentDetectorReadout{1,1}) == 1
                        app.detectorReadoutEditField.Value = char(instrumentDetectorReadout{1,1});
                    end
                  % multiple numeric
                    instrumentDetectorSize = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/detectorSize'),2);
                    if ~isempty(instrumentDetectorSize{1,1}) == 1
                        app.detectorSizeEditField.Value = instrumentDetectorSize{1,1}(1,1);
                        app.detectorSizeEditField_2.Value = instrumentDetectorSize{1,1}(1,2);
                    end
                    instrumentLaserDirection = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/laserIncidenceDirection'),2);
                    if ~isempty(instrumentLaserDirection{1,1}) == 1
                        app.laserIncidenceDirectionEditField.Value = instrumentLaserDirection{1,1}(1,1);
                        app.laserIncidenceDirectionEditField_2.Value = instrumentLaserDirection{1,1}(1,2);
                        app.laserIncidenceDirectionEditField_3.Value = instrumentLaserDirection{1,1}(1,3);
                    end
                  % numeric
                    instrumentFligthPathLength = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/flightPathLength'),2);
                    if ~isempty(instrumentFligthPathLength{1,1}) == 1
                        app.flightPathLengthEditField.Value = instrumentFligthPathLength{1,1};
                    end
                    instrumentT0 = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/t0'),2);
                    if ~isempty(instrumentT0{1,1}) == 1
                        app.t0EditField.Value = instrumentT0{1,1};
                    end
                    instrumentDetectionEfficiency = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/detectionEfficiency'),2);
                    if ~isempty(instrumentDetectionEfficiency{1,1}) == 1
                        app.detectionEfficiencyEditField.Value = instrumentDetectionEfficiency{1,1};
                    end
                    instrumentDetectorResolution = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/detectorResolution'),2);
                    if ~isempty(instrumentDetectorResolution{1,1}) == 1
                        app.detectorResolutionEditField.Value = instrumentDetectorResolution{1,1};
                    end
                  % date
                    instrumentCalibDate = app.MetaWS(contains(app.MetaWS(:,1),'/instrument/lastCalibrationDate'),2);
                    if isempty(instrumentCalibDate{1,1}) == 0
                        app.lastCalibrationDateDatePicker.Value = datetime(char(instrumentCalibDate{1,1}), 'Format', 'yyyy-MM-dd');
                    end
               

             % APT Data -> Experiment
                  % string
                    experimentType = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/type'),2);
                    if ~isempty(experimentType{1,1}) == 1
                        app.typeEditField_2.Value = char(experimentType{1,1});
                    end
                    experimentInstrumentOperator = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/instrumentOperator'),2);
                    if ~isempty(experimentInstrumentOperator{1,1}) == 1
                        app.instrumentOperatorEditField.Value = char(experimentInstrumentOperator{1,1});
                    end
                    beginTimeZone = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/beginTimeZone'),2);
                    if ~isempty(beginTimeZone) == 1
                        app.beginTimeZoneEditField.Value = char(beginTimeZone{1,1});
                    end
                    endTimeZone = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/endTimeZone'),2);
                    if ~isempty(endTimeZone) == 1
                        app.endTimeZoneEditField.Value = char(endTimeZone{1,1});
                    end
                    experimentExUniqueIdentifier = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/uniqueIdentifier'),2);
                    if ~isempty(experimentExUniqueIdentifier{1,1}) == 1
                        app.uniqueIdentifierEditField_2.Value = char(experimentExUniqueIdentifier{1,1});
                    end
                    experimentPublicationDOI = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/data/publicationDOI'),2);
                    if ~isempty(experimentPublicationDOI) == 1
                        app.publicationDOIEditField.Value = char(experimentPublicationDOI{1,1});
                    end
                    experimentNotes = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/notes'),2);
                    if ~isempty(experimentNotes{1,1}) == 1
                        app.notesTextArea_2.Value = char(experimentNotes{1,1});
                    end
                  % categorical
                    experimentStatus = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/status'),2);
                    if isempty(experimentStatus) == 1
                       app.statusDropDown.Value = 'tbd';
                    else
                        app.statusDropDown.Value = char(experimentStatus{1});
                    end
                    experimentResult = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/result'),2);
                    if isempty(experimentResult) == 1
                        app.resultDropDown.Value = 'tbd';
                    else
                        app.resultDropDown.Value = char(experimentResult{1});
                    end
                  % datetime
                    experimentBeginDate = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/beginDateTime'),2);
                    if isempty(experimentBeginDate{1,1}) == 0
                        t = datetime(experimentBeginDate,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS', 'Format', 'yyyy-MM-dd HH:mm:ss.SSS');
                        beginDate = datestr(t, 'yyyy-mm-dd');
                        beginTime = datestr(t,'HH:MM:SS.FFF');
                        app.beginDateTimeDatePicker.Value = datetime(beginDate, 'Format', 'yyyy-MM-dd');
                        app.startTimeEditField.Value = beginTime;
                    end
                    experimentEndDate = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/endDateTime'),2);
                    assignin("base","experimentEndDate", experimentEndDate)
                    if isempty(experimentEndDate{1,1}) == 0
                        p = datetime(experimentEndDate,'InputFormat','yyyy-MM-dd HH:mm:ss.SSS', 'Format', 'yyyy-MM-dd HH:mm:ss.SSS');
                        endDate = datestr(p, 'yyyy-mm-dd');
                        endTime = datestr(p,'HH:MM:SS.FFF');
                        app.endDateTimeDatePicker.Value = datetime(endDate, 'Format', 'yyyy-MM-dd');
                        app.endTimeEditField.Value = endTime;
                    end
                    experimentDataEmbargo = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/dataEmbargoPeriodEndDate'),2);
                    if ~isempty(experimentDataEmbargo{1,1}) == 1
                        app.dataEmbargoPeriodEndDateDatePicker.Value = datetime(char(experimentDataEmbargo{1,1}), 'Format', 'yyyy-MM-dd');
                    end

            % APT Data -> APT experiment
                  % categorical
                    aptPulseT = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/pulseType'),2);
                    if isempty(aptPulseT) == 1
                        app.pulseTypeDropDown.Value = 'tbd';
                    else
                        app.pulseTypeDropDown.Value = char(aptPulseT{1});
                    end
                  % numeric
                    aptTActual = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/specimenTemperatureActual'),2);
                    if ~isempty(aptTActual{1,1}) == 1
                        app.specimenTemperatureActualEditField.Value = aptTActual{1,1};
                    end
                    aptTNominal = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/specimenTemperatureNominal'),2);
                    if ~isempty(aptTNominal{1,1}) == 1
                        app.specimenTemperatureNominalEditField.Value = aptTNominal{1,1};
                    end
                    aptChamberP = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/measurementChamberPressure'),2);
                    if ~isempty(aptChamberP{1,1}) == 1
                        app.measurementChamberPressureEditField.Value = aptChamberP{1,1};
                    end
                    aptFrac = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/pulseFraction'),2);
                    if ~isempty(aptFrac{1,1}) == 1
                        app.pulseFractionEditField.Value = aptFrac{1,1};
                    end
                    aptPulse = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/laserPulseEnergy'),2);
                    if ~isempty(aptPulse{1,1}) == 1
                        app.laserPulseEnergyEditField.Value = aptPulse{1,1};
                    end
                    aptPulsePeriod = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/pulsePeriod'),2);
                    if ~isempty(aptPulsePeriod{1,1}) == 1
                        app.pulsePeriodEditField.Value = aptPulsePeriod{1,1};
                    end
                  % multiple numeric  
                    aptLaserPos = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/laserPosition'),2);
                    if ~isempty(aptLaserPos{1,1}) == 1
                        app.laserPositionEditField.Value = aptLaserPos{1,1}(1,1);
                        app.laserPositionEditField_2.Value = aptLaserPos{1,1}(1,2);
                        app.laserPositionEditField_3.Value = aptLaserPos{1,1}(1,3);
                    end
                    aptStage = app.MetaWS(contains(app.MetaWS(:,1),'/experiment/specimenStagePosition'),2);
                    if ~isempty(aptStage{1,1}) == 1
                        app.specimenStagePositionEditField.Value = aptStage{1,1}(1,1);
                        app.specimenStagePositionEditField_2.Value = aptStage{1,1}(1,2);
                        app.specimenStagePositionEditField_3.Value = aptStage{1,1}(1,3);
                    end
                    
            % APT Data -> Specimen Preparation
                  % string
                    aptInstrument = app.MetaWS(contains(app.MetaWS(:,1),'/preparation/instrument'),2);
                    if ~isempty(aptInstrument{1,1}) == 1
                        app.instrumentEditField.Value = char(aptInstrument{1,1});
                    end
                    aptElectroAgent = app.MetaWS(contains(app.MetaWS(:,1),'/preparation/electroPolishingAgent'),2);
                    if ~isempty(aptElectroAgent{1,1}) == 1
                        app.electroPolishingAgentEditField.Value = char(aptElectroAgent{1,1});
                    end
                  % numeric
                    aptFinalBeam = app.MetaWS(contains(app.MetaWS(:,1),'/preparation/finalIonBeamEnergy'),2);
                    if ~isempty(aptFinalBeam{1,1}) == 1
                        app.finalIonBeamEnergyEditField.Value = aptFinalBeam{1,1};
                    end
                    aptElectroVoltage = app.MetaWS(contains(app.MetaWS(:,1),'/preparation/electroPolishingVoltage'),2);
                    if ~isempty(aptElectroVoltage{1,1}) == 1
                        app.electroPolishingVoltageEditField.Value = aptElectroVoltage{1,1};
                    end
                    aptElectroTemp = app.MetaWS(contains(app.MetaWS(:,1),'/preparation/electroPolishingTemperature'),2);
                    if ~isempty(aptElectroTemp{1,1}) == 1
                        app.electropolishingTemperatureEditField.Value = aptElectroTemp{1,1};
                    end
                  % categorical
                    aptMethod = app.MetaWS(contains(app.MetaWS(:,1),'/preparation/method'),2);
                    if isempty(aptMethod) == 1
                        app.methodDropDown.Value = 'tbd';
                    else
                        app.methodDropDown.Value = char(aptMethod{1});
                    end


                % Date time conversion
                % B = char(app.MetaWS(contains(app.MetaWS(:,1),'/project/startDate'),2))
                % G = datetime(B, 'Format', 'yyyy-MM-dd')
                % app.startDateDatePicker.Value = G
                % back conversion c{1} = categorical(cellstr(B))
%                 assignin("base","C",C)
%                 assignin("base","B",B)
%                 assignin("base","D",D)


        end

        % Button pushed function: SaveToWSButton
        function SaveToWSButtonPushed(app, event)
            % save the new variable in the MetaWs Property
            
            % output format for dates
            formatOut = 'yyyy-mm-dd';
            
            % General
                % Project
                    % string
                   app.MetaWS(contains(app.MetaWS(:,1),'/project/identifier'),2) = cellstr(app.identifierEditField.Value);
                   app.MetaWS(contains(app.MetaWS(:,1),'/project/contactPerson'),2) = cellstr(app.contactPersonEditField.Value); 
                   app.MetaWS(contains(app.MetaWS(:,1),'/project/dataSharingLicense'),2) = cellstr(app.dataSharingLicenseEditField.Value); 
                   % categorical
                   exStatus{1,1} = categorical(cellstr(app.confidentialityDropDown.Value));
                   app.MetaWS(contains(app.MetaWS(:,1),'/project/confidentiality'),2) = exStatus;
                   % datetime
                   if isnat(app.startDateDatePicker.Value) == 0 
                    app.MetaWS(contains(app.MetaWS(:,1),'/project/startDate'),2) = cellstr(datestr(app.startDateDatePicker.Value, formatOut));
                   end
                   if isnat(app.endDateDatePicker.Value) == 0
                    app.MetaWS(contains(app.MetaWS(:,1),'/project/endDate'),2) = cellstr(datestr(app.endDateDatePicker.Value,formatOut));
                   end
                % Specimen
                    % string
                    app.MetaWS(contains(app.MetaWS(:,1),'/specimen/name'),2) = cellstr(app.nameEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/specimen/description'),2) = cellstr(app.descriptionEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/specimen/uniqueIdentifier'),2) = cellstr(app.uniqueIdentifierEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/specimen/notes'),2) = cellstr(app.notesTextArea.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/specimen/materialType'),2) = cellstr(app.materialTypeEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/specimen/compositionNominal'),2) = cellstr(app.compositionNominalEditField.Value);
                % Experiment
                    % string
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/institution'),2) = cellstr(app.institutionEditField.Value);
                    % numerical
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/longitude'),2) = num2cell(app.longitudeEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/latitude'),2) = num2cell(app.latitudeEditField.Value);
                % Instrument
                    % string
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/family'),2) = cellstr(app.familyEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/type'),2) = cellstr(app.typeEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/acquisitionDevice'),2) = cellstr(app.acquisitionDeviceEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/manufacturer'),2) = cellstr(app.manufacturerEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/apertureType'),2) = cellstr(app.apertureTypeEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/apertureUniqueIdentifier'),2) = cellstr(app.apertureUniqueIdentifierEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/detectorReadout'),2) = cellstr(app.detectorReadoutEditField.Value);
                    % date
                    if isnat(app.lastCalibrationDateDatePicker.Value) == 0
                     app.MetaWS(contains(app.MetaWS(:,1),'/instrument/lastCalibrationDate'),2) = cellstr(datestr(app.lastCalibrationDateDatePicker.Value,formatOut));
                    end 
                    % numeric
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/flightPathLength'),2) = num2cell(app.flightPathLengthEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/t0'),2) = num2cell(app.t0EditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/detectionEfficiency'),2) = num2cell(app.detectionEfficiencyEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/detectorResolution'),2) = num2cell(app.detectorResolutionEditField.Value);
                    % multiple numeric fields
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/detectorSize'),2) = {[app.detectorSizeEditField.Value, app.detectorSizeEditField_2.Value]};
                    app.MetaWS(contains(app.MetaWS(:,1),'/instrument/laserIncidenceDirection'),2) = {[app.laserIncidenceDirectionEditField.Value, app.laserIncidenceDirectionEditField_2.Value, app.laserIncidenceDirectionEditField_3.Value]};
                    
            % APT Data
                % Experiment
                    % string
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/type'),2) = cellstr(app.typeEditField_2.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/instrumentOperator'),2) = cellstr(app.instrumentOperatorEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/beginTimeZone'),2) = cellstr(app.beginTimeZoneEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/endTimeZone'),2) = cellstr(app.endTimeZoneEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/uniqueIdentifier'),2) = cellstr(app.uniqueIdentifierEditField_2.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/data/publicationDOI'),2) = cellstr(app.publicationDOIEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/notes'),2) = cellstr(app.notesTextArea_2.Value);
                    % categorical
                    exStatus{1,1} = categorical(cellstr(app.statusDropDown.Value));
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/status'),2) = exStatus;
                    exResult{1,1} = categorical(cellstr(app.resultDropDown.Value));
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/result'),2) = exResult;
                    % date
                    if isnat(app.beginDateTimeDatePicker.Value) == 0
                        exBeginDateTime = [datestr(app.beginDateTimeDatePicker.Value, 'yyyy-mm-dd') ' ' app.startTimeEditField.Value];
                        app.MetaWS(contains(app.MetaWS(:,1),'/experiment/beginDateTime'),2) = cellstr(exBeginDateTime);
                    end
                    if isnat(app.endDateTimeDatePicker.Value) == 0
                        exEndDateTime = [datestr(app.endDateTimeDatePicker.Value, 'yyyy-mm-dd') ' ' app.endTimeEditField.Value];
                        app.MetaWS(contains(app.MetaWS(:,1),'/experiment/endDateTime'),2) = cellstr(exEndDateTime);
                    end
                    if isnat(app.dataEmbargoPeriodEndDateDatePicker.Value) == 0
                     app.MetaWS(contains(app.MetaWS(:,1),'/experiment/dataEmbargoPeriodEndDate'),2) = cellstr(datestr(app.dataEmbargoPeriodEndDateDatePicker.Value,formatOut));
                    end
                % APT Experiment
                    % categorical
                    expulseType{1,1} = categorical(cellstr(app.pulseTypeDropDown.Value));
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/pulseType'),2) = expulseType;
                    % numeric
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/specimenTemperatureActual'),2) = num2cell(app.specimenTemperatureActualEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/specimenTemperatureNominal'),2) = num2cell(app.specimenTemperatureNominalEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/measurementChamberPressure'),2) = num2cell(app.measurementChamberPressureEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/pulseFraction'),2) = num2cell(app.pulseFractionEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/laserPulseEnergy'),2) = num2cell(app.laserPulseEnergyEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/pulsePeriod'),2) = num2cell(app.pulsePeriodEditField.Value);
                    % multiple numeric entries
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/laserPosition'),2) = {[app.laserPositionEditField.Value, app.laserPositionEditField_2.Value, app.laserPositionEditField_3.Value]};
                    app.MetaWS(contains(app.MetaWS(:,1),'/experiment/specimenStagePosition'),2) = {[app.specimenStagePositionEditField.Value, app.specimenStagePositionEditField_2.Value, app.specimenStagePositionEditField_3.Value]};
                % Specimen Preparation
                    % string
                    app.MetaWS(contains(app.MetaWS(:,1),'/preparation/instrument'),2) = cellstr(app.instrumentEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/preparation/electroPolishingAgent'),2) = cellstr(app.electroPolishingAgentEditField.Value);
                    % categorical
                    exSpecMethod{1,1} = categorical(cellstr(app.methodDropDown.Value));
                    app.MetaWS(contains(app.MetaWS(:,1),'/preparation/method'),2) = exSpecMethod;
                    % numeric
                    app.MetaWS(contains(app.MetaWS(:,1),'/preparation/finalIonBeamEnergy'),2) = num2cell(app.finalIonBeamEnergyEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/preparation/electroPolishingVoltage'),2) = num2cell(app.electroPolishingVoltageEditField.Value);
                    app.MetaWS(contains(app.MetaWS(:,1),'/preparation/electroPolishingTemperature'),2) = num2cell(app.electropolishingTemperatureEditField.Value);


%             D = app.endDateDatePicker
%             E = app.startDateDatePicker
%             assignin("base","B",B)
%             assignin("base","C",C)
            %assignin("base","D",D)
            
            assignin("base",app.WSStoreNameEditField.Value,app.MetaWS)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.LoadmetadatafromWorkspacePanel = uipanel(app.GeneralTab);
            app.LoadmetadatafromWorkspacePanel.Title = 'Load meta data from Workspace';
            app.WSMetaVariableEditFieldLabel = uilabel(app.LoadmetadatafromWorkspacePanel);
            app.WSMetaVariableEditFieldLabel.Text = 'WSMetaVariable';
            app.WSMetaVariableEditField = uieditfield(app.LoadmetadatafromWorkspacePanel, 'text');
            app.LoadButton = uibutton(app.LoadmetadatafromWorkspacePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Image = uiimage(app.LoadmetadatafromWorkspacePanel);
            app.Image.ImageSource = 'Logo.png';
            app.ProjectPanel = uipanel(app.GeneralTab);
            app.ProjectPanel.Title = 'Project';
            app.identifierEditFieldLabel = uilabel(app.ProjectPanel);
            app.identifierEditFieldLabel.Text = 'identifier';
            app.identifierEditField = uieditfield(app.ProjectPanel, 'text');
            app.contactPersonEditFieldLabel = uilabel(app.ProjectPanel);
            app.contactPersonEditFieldLabel.Text = 'contactPerson';
            app.contactPersonEditField = uieditfield(app.ProjectPanel, 'text');
            app.confidentialityDropDownLabel = uilabel(app.ProjectPanel);
            app.confidentialityDropDownLabel.Text = 'confidentiality';
            app.confidentialityDropDown = uidropdown(app.ProjectPanel);
            app.confidentialityDropDown.Items = {'tbd', 'open', 'embargoed', 'confidental'};
            app.confidentialityDropDown.Value = 'open';
            app.dataSharingLicenseEditFieldLabel = uilabel(app.ProjectPanel);
            app.dataSharingLicenseEditFieldLabel.Text = 'dataSharingLicense';
            app.dataSharingLicenseEditField = uieditfield(app.ProjectPanel, 'text');
            app.startDateDatePickerLabel = uilabel(app.ProjectPanel);
            app.startDateDatePickerLabel.Text = 'startDate';
            app.startDateDatePicker = uidatepicker(app.ProjectPanel);
            app.startDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.endDateDatePickerLabel = uilabel(app.ProjectPanel);
            app.endDateDatePickerLabel.Text = 'endDate';
            app.endDateDatePicker = uidatepicker(app.ProjectPanel);
            app.endDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.ExperimentPanel = uipanel(app.GeneralTab);
            app.ExperimentPanel.Title = 'Experiment';
            app.institutionEditFieldLabel = uilabel(app.ExperimentPanel);
            app.institutionEditFieldLabel.Text = 'institution';
            app.institutionEditField = uieditfield(app.ExperimentPanel, 'text');
            app.longitudeEditFieldLabel = uilabel(app.ExperimentPanel);
            app.longitudeEditFieldLabel.Text = 'longitude';
            app.longitudeEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.latitudeEditFieldLabel = uilabel(app.ExperimentPanel);
            app.latitudeEditFieldLabel.Text = 'latitude';
            app.latitudeEditField = uieditfield(app.ExperimentPanel, 'numeric');
            app.InstrumentPanel = uipanel(app.GeneralTab);
            app.InstrumentPanel.Title = 'Instrument';
            app.familyEditFieldLabel = uilabel(app.InstrumentPanel);
            app.familyEditFieldLabel.Text = 'family';
            app.familyEditField = uieditfield(app.InstrumentPanel, 'text');
            app.typeEditFieldLabel = uilabel(app.InstrumentPanel);
            app.typeEditFieldLabel.Text = 'type';
            app.typeEditField = uieditfield(app.InstrumentPanel, 'text');
            app.acquisitionDeviceEditFieldLabel = uilabel(app.InstrumentPanel);
            app.acquisitionDeviceEditFieldLabel.Text = 'acquisitionDevice';
            app.acquisitionDeviceEditField = uieditfield(app.InstrumentPanel, 'text');
            app.manufacturerEditFieldLabel = uilabel(app.InstrumentPanel);
            app.manufacturerEditFieldLabel.Text = 'manufacturer';
            app.manufacturerEditField = uieditfield(app.InstrumentPanel, 'text');
            app.lastCalibrationDateDatePickerLabel = uilabel(app.InstrumentPanel);
            app.lastCalibrationDateDatePickerLabel.Text = 'lastCalibrationDate';
            app.lastCalibrationDateDatePicker = uidatepicker(app.InstrumentPanel);
            app.lastCalibrationDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.flightPathLengthEditFieldLabel = uilabel(app.InstrumentPanel);
            app.flightPathLengthEditFieldLabel.Text = 'flightPathLength';
            app.flightPathLengthEditField = uieditfield(app.InstrumentPanel, 'numeric');
            app.t0EditFieldLabel = uilabel(app.InstrumentPanel);
            app.t0EditFieldLabel.Text = 't0';
            app.t0EditField = uieditfield(app.InstrumentPanel, 'numeric');
            app.detectionEfficiencyEditFieldLabel = uilabel(app.InstrumentPanel);
            app.detectionEfficiencyEditFieldLabel.Text = 'detectionEfficiency';
            app.detectionEfficiencyEditField = uieditfield(app.InstrumentPanel, 'numeric');
            app.apertureTypeEditFieldLabel = uilabel(app.InstrumentPanel);
            app.apertureTypeEditFieldLabel.Text = 'apertureType';
            app.apertureTypeEditField = uieditfield(app.InstrumentPanel, 'text');
            app.apertureUniqueIdentifierEditFieldLabel = uilabel(app.InstrumentPanel);
            app.apertureUniqueIdentifierEditFieldLabel.Text = 'apertureUniqueIdentifier';
            app.apertureUniqueIdentifierEditField = uieditfield(app.InstrumentPanel, 'text');
            app.detectorReadoutEditFieldLabel = uilabel(app.InstrumentPanel);
            app.detectorReadoutEditFieldLabel.Text = 'detectorReadout';
            app.detectorReadoutEditField = uieditfield(app.InstrumentPanel, 'text');
            app.detectorResolutionEditFieldLabel = uilabel(app.InstrumentPanel);
            app.detectorResolutionEditFieldLabel.Text = 'detectorResolution';
            app.detectorResolutionEditField = uieditfield(app.InstrumentPanel, 'numeric');
            app.detectorSizeEditFieldLabel = uilabel(app.InstrumentPanel);
            app.detectorSizeEditFieldLabel.Text = 'detectorSize';
            app.detectorSizeEditField = uieditfield(app.InstrumentPanel, 'numeric');
            app.laserIncidenceDirectionEditFieldLabel = uilabel(app.InstrumentPanel);
            app.laserIncidenceDirectionEditFieldLabel.Text = 'laserIncidenceDirection';
            app.laserIncidenceDirectionEditField = uieditfield(app.InstrumentPanel, 'numeric');
            app.detectorSizeEditField_2 = uieditfield(app.InstrumentPanel, 'numeric');
            app.laserIncidenceDirectionEditField_2 = uieditfield(app.InstrumentPanel, 'numeric');
            app.laserIncidenceDirectionEditField_3 = uieditfield(app.InstrumentPanel, 'numeric');
            app.SpecimenPanel = uipanel(app.GeneralTab);
            app.SpecimenPanel.Title = 'Specimen';
            app.nameEditFieldLabel = uilabel(app.SpecimenPanel);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.SpecimenPanel, 'text');
            app.descriptionEditFieldLabel = uilabel(app.SpecimenPanel);
            app.descriptionEditFieldLabel.Text = 'description';
            app.descriptionEditField = uieditfield(app.SpecimenPanel, 'text');
            app.uniqueIdentifierEditFieldLabel = uilabel(app.SpecimenPanel);
            app.uniqueIdentifierEditFieldLabel.Text = 'uniqueIdentifier';
            app.uniqueIdentifierEditField = uieditfield(app.SpecimenPanel, 'text');
            app.materialTypeEditFieldLabel = uilabel(app.SpecimenPanel);
            app.materialTypeEditFieldLabel.Text = 'materialType';
            app.materialTypeEditField = uieditfield(app.SpecimenPanel, 'text');
            app.compositionNominalEditFieldLabel = uilabel(app.SpecimenPanel);
            app.compositionNominalEditFieldLabel.Text = 'compositionNominal';
            app.compositionNominalEditField = uieditfield(app.SpecimenPanel, 'text');
            app.notesTextAreaLabel = uilabel(app.SpecimenPanel);
            app.notesTextAreaLabel.Text = 'notes';
            app.notesTextArea = uitextarea(app.SpecimenPanel);
            app.APTDataTab = uitab(app.TabGroup);
            app.APTDataTab.Title = 'APT Data';
            app.APTExperimentPanel = uipanel(app.APTDataTab);
            app.APTExperimentPanel.Title = 'APT Experiment';
            app.pulseTypeDropDownLabel = uilabel(app.APTExperimentPanel);
            app.pulseTypeDropDownLabel.Text = 'pulseType';
            app.pulseTypeDropDown = uidropdown(app.APTExperimentPanel);
            app.pulseTypeDropDown.Items = {'tbd', 'voltagePulsed', 'laserPulsed'};
            app.pulseTypeDropDown.Value = 'voltagePulsed';
            app.specimenTemperatureActualEditFieldLabel = uilabel(app.APTExperimentPanel);
            app.specimenTemperatureActualEditFieldLabel.Text = 'specimenTemperatureActual';
            app.specimenTemperatureActualEditField = uieditfield(app.APTExperimentPanel, 'numeric');
            app.specimenTemperatureNominalEditFieldLabel = uilabel(app.APTExperimentPanel);
            app.specimenTemperatureNominalEditFieldLabel.Text = 'specimenTemperatureNominal';
            app.specimenTemperatureNominalEditField = uieditfield(app.APTExperimentPanel, 'numeric');
            app.measurementChamberPressureEditFieldLabel = uilabel(app.APTExperimentPanel);
            app.measurementChamberPressureEditFieldLabel.Text = 'measurementChamberPressure';
            app.measurementChamberPressureEditField = uieditfield(app.APTExperimentPanel, 'numeric');
            app.pulseFractionEditFieldLabel = uilabel(app.APTExperimentPanel);
            app.pulseFractionEditFieldLabel.Text = 'pulseFraction';
            app.pulseFractionEditField = uieditfield(app.APTExperimentPanel, 'numeric');
            app.laserPulseEnergyEditFieldLabel = uilabel(app.APTExperimentPanel);
            app.laserPulseEnergyEditFieldLabel.Text = 'laserPulseEnergy';
            app.laserPulseEnergyEditField = uieditfield(app.APTExperimentPanel, 'numeric');
            app.laserPositionEditFieldLabel = uilabel(app.APTExperimentPanel);
            app.laserPositionEditFieldLabel.Text = 'laserPosition';
            app.laserPositionEditField = uieditfield(app.APTExperimentPanel, 'numeric');
            app.pulsePeriodEditFieldLabel = uilabel(app.APTExperimentPanel);
            app.pulsePeriodEditFieldLabel.Text = 'pulsePeriod';
            app.pulsePeriodEditField = uieditfield(app.APTExperimentPanel, 'numeric');
            app.specimenStagePositionEditFieldLabel = uilabel(app.APTExperimentPanel);
            app.specimenStagePositionEditFieldLabel.Text = 'specimenStagePosition';
            app.specimenStagePositionEditField = uieditfield(app.APTExperimentPanel, 'numeric');
            app.laserPositionEditField_2 = uieditfield(app.APTExperimentPanel, 'numeric');
            app.laserPositionEditField_3 = uieditfield(app.APTExperimentPanel, 'numeric');
            app.specimenStagePositionEditField_2 = uieditfield(app.APTExperimentPanel, 'numeric');
            app.specimenStagePositionEditField_3 = uieditfield(app.APTExperimentPanel, 'numeric');
            app.SpecimenPreparationPanel = uipanel(app.APTDataTab);
            app.SpecimenPreparationPanel.Title = 'Specimen Preparation';
            app.methodDropDownLabel = uilabel(app.SpecimenPreparationPanel);
            app.methodDropDownLabel.Text = 'method';
            app.methodDropDown = uidropdown(app.SpecimenPreparationPanel);
            app.methodDropDown.Items = {'tbd', 'electropolishing', 'FIB', 'CryoFIB', 'CryoElectropolishing'};
            app.methodDropDown.Value = 'tbd';
            app.instrumentEditFieldLabel = uilabel(app.SpecimenPreparationPanel);
            app.instrumentEditFieldLabel.Text = 'instrument';
            app.instrumentEditField = uieditfield(app.SpecimenPreparationPanel, 'text');
            app.finalIonBeamEnergyEditFieldLabel = uilabel(app.SpecimenPreparationPanel);
            app.finalIonBeamEnergyEditFieldLabel.Text = 'finalIonBeamEnergy';
            app.finalIonBeamEnergyEditField = uieditfield(app.SpecimenPreparationPanel, 'numeric');
            app.electroPolishingAgentEditFieldLabel = uilabel(app.SpecimenPreparationPanel);
            app.electroPolishingAgentEditFieldLabel.Text = 'electroPolishingAgent';
            app.electroPolishingAgentEditField = uieditfield(app.SpecimenPreparationPanel, 'text');
            app.electroPolishingVoltageEditFieldLabel = uilabel(app.SpecimenPreparationPanel);
            app.electroPolishingVoltageEditFieldLabel.Text = 'electroPolishingVoltage';
            app.electroPolishingVoltageEditField = uieditfield(app.SpecimenPreparationPanel, 'numeric');
            app.electropolishingTemperatureEditFieldLabel = uilabel(app.SpecimenPreparationPanel);
            app.electropolishingTemperatureEditFieldLabel.Text = 'electropolishingTemperature';
            app.electropolishingTemperatureEditField = uieditfield(app.SpecimenPreparationPanel, 'numeric');
            app.ExperimentPanel_2 = uipanel(app.APTDataTab);
            app.ExperimentPanel_2.Title = 'Experiment';
            app.typeEditField_2Label = uilabel(app.ExperimentPanel_2);
            app.typeEditField_2Label.Text = 'type';
            app.typeEditField_2 = uieditfield(app.ExperimentPanel_2, 'text');
            app.instrumentOperatorEditFieldLabel = uilabel(app.ExperimentPanel_2);
            app.instrumentOperatorEditFieldLabel.Text = 'instrumentOperator';
            app.instrumentOperatorEditField = uieditfield(app.ExperimentPanel_2, 'text');
            app.beginTimeZoneEditFieldLabel = uilabel(app.ExperimentPanel_2);
            app.beginTimeZoneEditFieldLabel.Text = 'beginTimeZone';
            app.beginTimeZoneEditField = uieditfield(app.ExperimentPanel_2, 'text');
            app.beginDateTimeDatePickerLabel = uilabel(app.ExperimentPanel_2);
            app.beginDateTimeDatePickerLabel.Text = 'beginDateTime';
            app.beginDateTimeDatePicker = uidatepicker(app.ExperimentPanel_2);
            app.beginDateTimeDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.endDateTimeDatePickerLabel = uilabel(app.ExperimentPanel_2);
            app.endDateTimeDatePickerLabel.Text = 'endDateTime';
            app.endDateTimeDatePicker = uidatepicker(app.ExperimentPanel_2);
            app.endDateTimeDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.uniqueIdentifierEditField_2Label = uilabel(app.ExperimentPanel_2);
            app.uniqueIdentifierEditField_2Label.Text = 'uniqueIdentifier';
            app.uniqueIdentifierEditField_2 = uieditfield(app.ExperimentPanel_2, 'text');
            app.statusDropDownLabel = uilabel(app.ExperimentPanel_2);
            app.statusDropDownLabel.Text = 'status';
            app.statusDropDown = uidropdown(app.ExperimentPanel_2);
            app.statusDropDown.Items = {'planned', 'inProgress', 'finished', 'tbd'};
            app.statusDropDown.Value = 'tbd';
            app.resultDropDownLabel = uilabel(app.ExperimentPanel_2);
            app.resultDropDownLabel.Text = 'result';
            app.resultDropDown = uidropdown(app.ExperimentPanel_2);
            app.resultDropDown.Items = {'tbd', 'Bad Run', 'Good Run', 'Good But Short', 'Unknown', 'short', 'ok', 'questionable', 'failed'};
            app.resultDropDown.Value = 'tbd';
            app.notesTextArea_2Label = uilabel(app.ExperimentPanel_2);
            app.notesTextArea_2Label.Text = 'notes';
            app.notesTextArea_2 = uitextarea(app.ExperimentPanel_2);
            app.dataEmbargoPeriodEndDateDatePickerLabel = uilabel(app.ExperimentPanel_2);
            app.dataEmbargoPeriodEndDateDatePickerLabel.Text = 'dataEmbargoPeriodEndDate';
            app.dataEmbargoPeriodEndDateDatePicker = uidatepicker(app.ExperimentPanel_2);
            app.dataEmbargoPeriodEndDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.publicationDOIEditFieldLabel = uilabel(app.ExperimentPanel_2);
            app.publicationDOIEditFieldLabel.Text = 'publicationDOI';
            app.publicationDOIEditField = uieditfield(app.ExperimentPanel_2, 'text');
            app.endTimeZoneEditFieldLabel = uilabel(app.ExperimentPanel_2);
            app.endTimeZoneEditFieldLabel.Text = 'endTimeZone';
            app.endTimeZoneEditField = uieditfield(app.ExperimentPanel_2, 'text');
            app.startTimeEditFieldLabel = uilabel(app.ExperimentPanel_2);
            app.startTimeEditFieldLabel.Text = 'startTime';
            app.startTimeEditField = uieditfield(app.ExperimentPanel_2, 'text');
            app.startTimeEditField.Value = 'HH:mm:ss.SSS';
            app.endTimeEditFieldLabel = uilabel(app.ExperimentPanel_2);
            app.endTimeEditFieldLabel.Text = 'endTime';
            app.endTimeEditField = uieditfield(app.ExperimentPanel_2, 'text');
            app.endTimeEditField.Value = 'HH:mm:ss.SSS';
            app.SavePanel = uipanel(app.APTDataTab);
            app.SavePanel.Title = 'Save';
            app.SaveToWSButton = uibutton(app.SavePanel, 'push');
            app.SaveToWSButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToWSButtonPushed, true);
            app.SaveToWSButton.Text = 'SaveToWS';
            app.WSStoreNameEditFieldLabel = uilabel(app.SavePanel);
            app.WSStoreNameEditFieldLabel.Text = 'WSStoreName';
            app.WSStoreNameEditField = uieditfield(app.SavePanel, 'text');
            app.Image2 = uiimage(app.APTDataTab);
            app.Image2.ImageSource = 'Logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetaDataLEAP_03

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end