classdef volumeIsosurfaceWidget < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        isosurfacewidgetUIFigure  matlab.ui.Figure
        rotationZ                 matlab.ui.control.NumericEditField
        shiftxLabel_6             matlab.ui.control.Label
        rotationY                 matlab.ui.control.NumericEditField
        shiftxLabel_5             matlab.ui.control.Label
        rotationX                 matlab.ui.control.NumericEditField
        shiftxLabel_4             matlab.ui.control.Label
        shiftZ                    matlab.ui.control.NumericEditField
        shiftxLabel_3             matlab.ui.control.Label
        shiftY                    matlab.ui.control.NumericEditField
        shiftxLabel_2             matlab.ui.control.Label
        shiftX                    matlab.ui.control.NumericEditField
        shiftxLabel               matlab.ui.control.Label
        splitFVbutton             matlab.ui.control.Button
        createvariableButton      matlab.ui.control.Button
        minPatchSize              matlab.ui.control.NumericEditField
        filterPatchSizeButton     matlab.ui.control.Button
        changeColorButton         matlab.ui.control.Button
        isoValueEditField         matlab.ui.control.NumericEditField
        isovalueEditFieldLabel    matlab.ui.control.Label
        isoValueSlider            matlab.ui.control.Slider
        dispAxis                  matlab.ui.control.UIAxes
    end

    % (c) by Prof. Peter Felfer Group @FAU Erlangen-Nürnberg
    properties (Access = private)
        conc % concentration field
        histogram % histogram of concentration values
        bins % bins of the histogram
        histPlot % handle of histogram plot
        fv % isosurface object (type: patch)
        fvs % isosurface objects if surface has been split
        fvsHandles % plot handles of individual split patches
        isSplit % boolean denoting if surfaces are split
        plotAxis % handle of axis in which isosurface is plotted
        isosurfacePatch % handle of isosurface plot in plotAxis
        lineHandle % handle of line marking current selected concentration
        gv % grid vectors of isosurface
        isosurfColor % isosurface color
        isoLine % line in the histogram at the isovalue
        isoFormat % count or concentration
        isosurfaceShift % shift of isosurface as [x y z]
        isosurfaceRotation % rotation of isosurface in [eulerX eulerY and eulerZ]
    end
    
    methods (Access = private)
        
        function results = updateLocRot(app)
            isosurfaceShiftOld = app.isosurfaceShift; %read out old values befor updating
            isosurfaceRotationOld = app.isosurfaceRotation;

            app.isosurfaceRotation = [app.rotationX.Value app.rotationY.Value app.rotationZ.Value];
            app.isosurfaceShift = [app.shiftX.Value, app.shiftY.Value, app.shiftZ.Value];
            if app.isSplit
                for i=1:length(app.fvs)
                    app.fvs(i).vertices = app.fvs(i).vertices + repmat(app.isosurfaceShift,[height(app.fvs(i).vertices) 1]);
                end
                
            else
                %shift by difference between old and new shift values
                app.fv.vertices = app.fv.vertices + repmat(app.isosurfaceShift,[height(app.fv.vertices) 1]) - repmat(isosurfaceShiftOld,[height(app.fv.vertices) 1]);
                app.isosurfacePatch.Vertices = app.fv.vertices;
                app.isosurfacePatch.Faces = app.fv.faces;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, volume, binSize, plotAxis)
            if exist('plotAxis','var')
                app.plotAxis = plotAxis;
            else
                f = figure('Name','volume isosurface plot');
                app.plotAxis = axes(f);
                axisSpatialAptify;
                axis tight;
            end
            app.isSplit = false;

            app.isosurfaceShift = [0 0 0];
            app.isosurfaceRotation = [0 0 0];

            app.conc = volume;
            app.isoFormat = 'conc';
            
            [app.gv ~] = gridVectorsFromVolume(app.conc, binSize);
            
            % draw histogram of voxel values
            app.bins = double(linspace(min(app.conc(:)),max(app.conc(:)),100));
            app.histogram = hist(app.conc(:),app.bins);
            app.isoValueSlider.Limits = [app.bins(1) app.bins(end)]';
            app.isoValueSlider.Value = (app.bins(1)+app.bins(end))/2;
            app.histPlot = plot(app.bins,app.histogram,'Parent',app.dispAxis);
            app.histPlot.LineWidth = 2;
            
            % calculate isosurface
            app.fv = isosurface(app.gv{2}, app.gv{1}, app.gv{3}, app.conc,app.isoValueSlider.Value);
            verts = app.fv.vertices; % matlabs coordniate orientations are mixed up. need this weird fix swapping x and y.
            if ~isempty(verts)
                app.fv.vertices(:,2) = verts(:,1);
                app.fv.vertices(:,1) = verts(:,2);
            end
            app.isosurfacePatch = patch(app.plotAxis,app.fv);
            app.isosurfacePatch.FaceColor = [.0 .0 .5];
            app.isosurfacePatch.DisplayName = ['iso ' num2str(round(app.isoValueSlider.Value,4))];
            
            app.isosurfacePatch.FaceColor = [.5 .5 .5];

            
            % draw line at isovalue
            app.isoLine = line([app.isoValueSlider.Value app.isoValueSlider.Value],[0 max(app.histogram)],'Parent',app.dispAxis);
            app.isoLine.Color = 'r';
            app.isoLine.LineWidth = 2;
            axis equal;
            rotate3d on;
        end

        % Value changed function: isoValueSlider
        function isoValueSliderValueChanging(app, event)
            
            if app.isSplit
                delete(app.fvsHandles);
            end
            
            isoval = app.isoValueSlider.Value;
            app.isoValueEditField.Value = isoval;
            app.fv = isosurface(app.gv{2}, app.gv{1}, app.gv{3}, app.conc,app.isoValueSlider.Value);
            verts = app.fv.vertices;
            app.fv.vertices(:,2) = verts(:,1);
            app.fv.vertices(:,1) = verts(:,2);
            app.isosurfacePatch.Vertices = app.fv.vertices;
            app.isosurfacePatch.Faces = app.fv.faces;
            app.isosurfacePatch.DisplayName = ['iso ' num2str(round(app.isoValueSlider.Value,4))];
            app.isoLine.XData = [app.isoValueSlider.Value app.isoValueSlider.Value];
            app.isosurfacePatch.Visible = 'on';
            
            app.isSplit = false;
        end

        % Value changed function: isoValueEditField
        function isoValueEditFieldValueChanged(app, event)
            app.isoValueSlider.Value = app.isoValueEditField.Value;
            app.isoValueSliderValueChanging(app);
        end

        % Button pushed function: changeColorButton
        function changeColorButtonPushed(app, event)
            app.isosurfColor = uisetcolor();
            app.isosurfacePatch.FaceColor = app.isosurfColor;
        end

        % Button pushed function: filterPatchSizeButton
        function filterPatchSizeButtonPushed(app, event)
            app.fv = patchFilterSize(app.fv,app.minPatchSize.Value);
            app.isosurfacePatch.Vertices = app.fv.vertices;
            app.isosurfacePatch.Faces = app.fv.faces;
        end

        % Button pushed function: createvariableButton
        function createvariableButtonPushed(app, event)
            varName = ['isosurf_' num2str(round(app.isoValueSlider.Value,4))];
            varName(varName == '.') = '_';
                        
            if ~app.isSplit
                % isosurface as a whole is exported
                assignin('base',varName,app.fv);
                disp(['isosurface variable ' varName ' assigned in workspace']);
            else
                % uses all surfaces if nothing is selected. If some are selected, selection is used.
                numPatch = length(app.fvsHandles);
                
                %check which ones are slected
                isSelected = false(1,numPatch);
                for p = 1:numPatch
                    if strcmp(get(app.fvsHandles(p),'Selected'),'on')
                        isSelected(p) = true;
                    end
                end
                
                % export based on selection state
                if sum(isSelected)
                    assignin('base',varName,app.fvs);
                    assignin('base',[varName '_selection'],isSelected);
                    disp(['all split isosurface variables assigned in workspace as: ' varName]);
                    disp(['selected indices assigned in workspace as: ' varName '_selection']);
                else
                    assignin('base',varName,app.fvs);
                    disp(['all split isosurface variables assigned in workspace as: ' varName]);
                end
            end
        end

        % Button pushed function: splitFVbutton
        function splitFVbuttonButtonPushed(app, event)
            app.isSplit = true;
            % creates individual patches for each loose part of the isosurface
            app.fvs = splitFV(app.fv.faces,app.fv.vertices);
            
            % remove vertices and faces of original patch
            %app.isosurfacePatch.Vertices = [];
            %app.isosurfacePatch.Faces = [];
            app.isosurfacePatch.Visible = 'off';
            
            % plot individual patches with randomized colors (random hue)
            numParts = length(app.fvs);
            pColors = hsv(numParts);%rand(numParts,3);
            for p = 1:numParts
                app.fvsHandles(p) = patch(app.plotAxis,app.fvs(p),'FaceColor',pColors(p,:),...
                    'DisplayName',['iso ' num2str(p)]);
            end
            
        end

        % Value changed function: shiftX
        function shiftXValueChanged(app, event)
            updateLocRot(app);            
        end

        % Value changed function: shiftY
        function shiftYValueChanged(app, event)
            updateLocRot(app)
        end

        % Value changed function: shiftZ
        function shiftZValueChanged(app, event)
            updateLocRot(app)
        end

        % Callback function: not associated with a component
        function movexSpinnerValueChanged(app, event)
            %value = app.movexSpinner.Value;
            %app.shiftX.Value = app.shiftX.Value + increment;
            %updateLocRot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.isosurfacewidgetUIFigure = uifigure('Visible', 'off');
            app.isosurfacewidgetUIFigure.Name = 'isosurface widget';
            app.dispAxis = uiaxes(app.isosurfacewidgetUIFigure);
            ylabel(app.dispAxis, 'frequency')
            app.isoValueSlider = uislider(app.isosurfacewidgetUIFigure);
            app.isoValueSlider.ValueChangedFcn = createCallbackFcn(app, @isoValueSliderValueChanging, true);
            app.isovalueEditFieldLabel = uilabel(app.isosurfacewidgetUIFigure);
            app.isovalueEditFieldLabel.Text = 'isovalue';
            app.isoValueEditField = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            app.isoValueEditField.ValueChangedFcn = createCallbackFcn(app, @isoValueEditFieldValueChanged, true);
            app.changeColorButton = uibutton(app.isosurfacewidgetUIFigure, 'push');
            app.changeColorButton.ButtonPushedFcn = createCallbackFcn(app, @changeColorButtonPushed, true);
            app.changeColorButton.Text = 'change color';
            app.filterPatchSizeButton = uibutton(app.isosurfacewidgetUIFigure, 'push');
            app.filterPatchSizeButton.ButtonPushedFcn = createCallbackFcn(app, @filterPatchSizeButtonPushed, true);
            app.filterPatchSizeButton.Text = 'filter patch size';
            app.minPatchSize = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            app.minPatchSize.Value = 100;
            app.createvariableButton = uibutton(app.isosurfacewidgetUIFigure, 'push');
            app.createvariableButton.ButtonPushedFcn = createCallbackFcn(app, @createvariableButtonPushed, true);
            app.createvariableButton.Text = 'create variable';
            app.splitFVbutton = uibutton(app.isosurfacewidgetUIFigure, 'push');
            app.splitFVbutton.ButtonPushedFcn = createCallbackFcn(app, @splitFVbuttonButtonPushed, true);
            app.splitFVbutton.Text = 'split surfaces';
            app.shiftxLabel = uilabel(app.isosurfacewidgetUIFigure);
            app.shiftxLabel.Text = 'shift x';
            app.shiftX = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            app.shiftX.ValueChangedFcn = createCallbackFcn(app, @shiftXValueChanged, true);
            app.shiftxLabel_2 = uilabel(app.isosurfacewidgetUIFigure);
            app.shiftxLabel_2.Text = 'shift y';
            app.shiftY = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            app.shiftY.ValueChangedFcn = createCallbackFcn(app, @shiftYValueChanged, true);
            app.shiftxLabel_3 = uilabel(app.isosurfacewidgetUIFigure);
            app.shiftxLabel_3.Text = 'shift z';
            app.shiftZ = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            app.shiftZ.ValueChangedFcn = createCallbackFcn(app, @shiftZValueChanged, true);
            app.shiftxLabel_4 = uilabel(app.isosurfacewidgetUIFigure);
            app.shiftxLabel_4.Text = 'rotate x';
            app.rotationX = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            app.shiftxLabel_5 = uilabel(app.isosurfacewidgetUIFigure);
            app.shiftxLabel_5.Text = 'rotate y';
            app.rotationY = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            app.shiftxLabel_6 = uilabel(app.isosurfacewidgetUIFigure);
            app.shiftxLabel_6.Text = 'rotate z';
            app.rotationZ = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            % Show the figure after all components are created
            app.isosurfacewidgetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = volumeIsosurfaceWidget(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.isosurfacewidgetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.isosurfacewidgetUIFigure)
        end
    end
end