classdef isosurfaceWidget < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        isosurfacewidgetUIFigure  matlab.ui.Figure
        isoValueSlider            matlab.ui.control.Slider
        isovalueEditFieldLabel    matlab.ui.control.Label
        isoValueEditField         matlab.ui.control.NumericEditField
        changeColorButton         matlab.ui.control.Button
        filterPatchSizeButton     matlab.ui.control.Button
        minPatchSize              matlab.ui.control.NumericEditField
        createvariableButton      matlab.ui.control.Button
        dispAxis                  matlab.ui.control.UIAxes
        splitFVbutton             matlab.ui.control.Button
    end

    % (c) by Prof. Peter Felfer Group @FAU Erlangen-Nürnberg
    properties (Access = private)
        conc % concentration field
        histogram % histogram of concentration values
        bins % bins of the histogram
        histPlot % handle of histogram plot
        fv % isosurface object (type: patch)
        fvs % isosurface objects if surface has been split
        fvsHandles % plot handles of individual split patches
        isSplit % boolean denoting if surfaces are split
        plotAxis % handle of axis in which isosurface is plotted
        isosurfacePatch % handle of isosurface plot in plotAxis
        lineHandle % handle of line marking current selected concentration
        gv % grid vectors of isosurface
        isosurfColor % isosurface color
        isoLine % line in the histogram at the isovalue
        isoFormat % count or concentration
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pos, num, denom, bin, colorScheme, plotAxis)
            if exist('plotAxis','var')
                app.plotAxis = plotAxis;
            else
                f = figure('Name','isosurface plot');
                app.plotAxis = axes(f);
                axisSpatialAptify;
                axis tight;
            end
            app.isSplit = false;
            
            % calculate concentration field
            % pos can be a table based pos variable or empty. 
            % 
            % TO DO:
            % If it is empty, numerator (num) and denominator (denom) atoms are to be supplied as arrays.
            % app.gv = gridVectorFromPos(pos,bin);
            if istable(pos)
                [binCenters, binEdges] = binVectorsFromDistance([pos.x, pos.y, pos.z],[bin bin bin],'distance');
                app.gv = binCenters;
            else
                app.gv = gridVectorFromPos(pos,bin);
            end
            solVox = posToVoxel(pos,app.gv,num); % voxelisation of the numerator species


            if ~isempty(denom)
                vox = posToVoxel(pos,app.gv,denom); % voxelisation of the denominator species denom alle ionen eingeben 
                app.conc = solVox./vox;
                app.isoFormat = 'conc';
            else
                app.conc = solVox;
                app.isoFormat = 'cnt';
            end


            % draw histogram of voxel values
            app.bins = linspace(min(app.conc(:)),max(app.conc(:)),100);
            app.histogram = hist(app.conc(:),app.bins);
            app.isoValueSlider.Limits = [app.bins(1) app.bins(end)];
            app.isoValueSlider.Value = (app.bins(1)+app.bins(end))/2;
            app.histPlot = plot(app.bins,app.histogram,'Parent',app.dispAxis);
            app.histPlot.LineWidth = 2;
            
            % calculate isosurface
            app.fv = isosurface(app.gv{2}, app.gv{1}, app.gv{3}, app.conc,app.isoValueSlider.Value);
            verts = app.fv.vertices; % matlabs coordniate orientations are mixed up. need this weird fix swapping x and y.
            if ~isempty(verts)
                app.fv.vertices(:,2) = verts(:,1);
                app.fv.vertices(:,1) = verts(:,2);
            end
            app.isosurfacePatch = patch(app.plotAxis,app.fv);
            app.isosurfacePatch.FaceColor = [.0 .0 .5];
            app.isosurfacePatch.DisplayName = ['iso ' num2str(round(app.isoValueSlider.Value,4))];
            
            % color according to colorScheme if possible
            if length(num) == 1
                app.isosurfacePatch.FaceColor = colorScheme.color(colorScheme.ion == num{1},:);
                
            end
            
            
            % draw line at isovalue
            app.isoLine = line([app.isoValueSlider.Value app.isoValueSlider.Value],[0 max(app.histogram)],'Parent',app.dispAxis);
            app.isoLine.Color = 'r';
            app.isoLine.LineWidth = 2;
            axis equal;
            rotate3d on;
        end

        % Value changed function: isoValueSlider
        function isoValueSliderValueChanging(app, event)
            
            if app.isSplit
                delete(app.fvsHandles);
            end
            
            isoval = app.isoValueSlider.Value;
            app.isoValueEditField.Value = isoval;
            app.fv = isosurface(app.gv{2}, app.gv{1}, app.gv{3}, app.conc,app.isoValueSlider.Value);
            verts = app.fv.vertices;
            app.fv.vertices(:,2) = verts(:,1);
            app.fv.vertices(:,1) = verts(:,2);
            app.isosurfacePatch.Vertices = app.fv.vertices;
            app.isosurfacePatch.Faces = app.fv.faces;
            app.isosurfacePatch.DisplayName = ['iso ' num2str(round(app.isoValueSlider.Value,4))];
            app.isoLine.XData = [app.isoValueSlider.Value app.isoValueSlider.Value];
            app.isosurfacePatch.Visible = 'on';
            
            app.isSplit = false;
        end

        % Value changed function: isoValueEditField
        function isoValueEditFieldValueChanged(app, event)
            app.isoValueSlider.Value = app.isoValueEditField.Value;
            app.isoValueSliderValueChanging(app);
        end

        % Button pushed function: changeColorButton
        function changeColorButtonPushed(app, event)
            app.isosurfColor = uisetcolor();
            app.isosurfacePatch.FaceColor = app.isosurfColor;
        end

        % Button pushed function: filterPatchSizeButton
        function filterPatchSizeButtonPushed(app, event)
            app.fv = patchFilterSize(app.fv,app.minPatchSize.Value);
            app.isosurfacePatch.Vertices = app.fv.vertices;
            app.isosurfacePatch.Faces = app.fv.faces;
        end

        % Button pushed function: createvariableButton
        function createvariableButtonPushed(app, event)
            varName = ['isosurf_' num2str(round(app.isoValueSlider.Value,4))];
            varName(varName == '.') = '_';
                        
            if ~app.isSplit
                % isosurface as a whole is exported
                assignin('base',varName,app.fv);
                disp(['isosurface variable ' varName ' assigned in workspace']);
            else
                % uses all surfaces if nothing is selected. If some are selected, selection is used.
                numPatch = length(app.fvsHandles);
                
                %check which ones are slected
                isSelected = false(1,numPatch);
                for p = 1:numPatch
                    if strcmp(get(app.fvsHandles(p),'Selected'),'on')
                        isSelected(p) = true;
                    end
                end
                
                % export based on selection state
                if sum(isSelected)
                    assignin('base',varName,app.fvs);
                    assignin('base',[varName '_selection'],isSelected);
                    disp(['all split isosurface variables assigned in workspace as: ' varName]);
                    disp(['selected indices assigned in workspace as: ' varName '_selection']);
                else
                    assignin('base',varName,app.fvs);
                    disp(['all split isosurface variables assigned in workspace as: ' varName]);
                end
            end
        end

        % Button pushed function: splitFVbutton
        function splitFVbuttonButtonPushed(app, event)
            app.isSplit = true;
            % creates individual patches for each loose part of the isosurface
            app.fvs = splitFV(app.fv.faces,app.fv.vertices);
            
            % remove vertices and faces of original patch
            %app.isosurfacePatch.Vertices = [];
            %app.isosurfacePatch.Faces = [];
            app.isosurfacePatch.Visible = 'off';
            
            % plot individual patches with randomized colors (random hue)
            numParts = length(app.fvs);
            pColors = hsv(numParts);%rand(numParts,3);
            for p = 1:numParts
                app.fvsHandles(p) = patch(app.plotAxis,app.fvs(p),'FaceColor',pColors(p,:),...
                    'DisplayName',['iso ' num2str(p)]);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.isosurfacewidgetUIFigure = uifigure('Visible', 'off');
            app.isosurfacewidgetUIFigure.Name = 'isosurface widget';
            app.isoValueSlider = uislider(app.isosurfacewidgetUIFigure);
            app.isoValueSlider.ValueChangedFcn = createCallbackFcn(app, @isoValueSliderValueChanging, true);
            app.isovalueEditFieldLabel = uilabel(app.isosurfacewidgetUIFigure);
            app.isovalueEditFieldLabel.Text = 'isovalue';
            app.isoValueEditField = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            app.isoValueEditField.ValueChangedFcn = createCallbackFcn(app, @isoValueEditFieldValueChanged, true);
            app.changeColorButton = uibutton(app.isosurfacewidgetUIFigure, 'push');
            app.changeColorButton.ButtonPushedFcn = createCallbackFcn(app, @changeColorButtonPushed, true);
            app.changeColorButton.Text = 'change color';
            app.filterPatchSizeButton = uibutton(app.isosurfacewidgetUIFigure, 'push');
            app.filterPatchSizeButton.ButtonPushedFcn = createCallbackFcn(app, @filterPatchSizeButtonPushed, true);
            app.filterPatchSizeButton.Text = 'filter patch size';
            app.minPatchSize = uieditfield(app.isosurfacewidgetUIFigure, 'numeric');
            app.minPatchSize.Value = 100;
            app.createvariableButton = uibutton(app.isosurfacewidgetUIFigure, 'push');
            app.createvariableButton.ButtonPushedFcn = createCallbackFcn(app, @createvariableButtonPushed, true);
            app.createvariableButton.Text = 'create variable';
            app.dispAxis = uiaxes(app.isosurfacewidgetUIFigure);
            ylabel(app.dispAxis, 'frequency')
            app.splitFVbutton = uibutton(app.isosurfacewidgetUIFigure, 'push');
            app.splitFVbutton.ButtonPushedFcn = createCallbackFcn(app, @splitFVbuttonButtonPushed, true);
            app.splitFVbutton.Text = 'split surfaces';
            % Show the figure after all components are created
            app.isosurfacewidgetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isosurfaceWidget(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.isosurfacewidgetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.isosurfacewidgetUIFigure)
        end
    end
end