classdef cropping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        croppingUIFigure       matlab.ui.Figure
        gcaButton              matlab.ui.control.Button
        XSliderLabel           matlab.ui.control.Label
        XSlider                matlab.ui.control.Slider
        YSliderLabel           matlab.ui.control.Label
        YSlider                matlab.ui.control.Slider
        ZSliderLabel           matlab.ui.control.Label
        ZSlider                matlab.ui.control.Slider
        XspanEditFieldLabel    matlab.ui.control.Label
        XspanEditField         matlab.ui.control.NumericEditField
        YspanEditFieldLabel    matlab.ui.control.Label
        YspanEditField         matlab.ui.control.NumericEditField
        ZspanEditFieldLabel    matlab.ui.control.Label
        ZspanEditField         matlab.ui.control.NumericEditField
        xenableCheckBox        matlab.ui.control.CheckBox
        yenableCheckBox        matlab.ui.control.CheckBox
        zenableCheckBox        matlab.ui.control.CheckBox
        enableCheckBox         matlab.ui.control.CheckBox
        xMinLabel              matlab.ui.control.Label
        xMaxLabel              matlab.ui.control.Label
        yMinLabel              matlab.ui.control.Label
        yMaxLabel              matlab.ui.control.Label
        zMaxLabel              matlab.ui.control.Label
        zMinLabel              matlab.ui.control.Label
        ROIfromcropButton      matlab.ui.control.Button
        ROInameEditFieldLabel  matlab.ui.control.Label
        ROInameEditField       matlab.ui.control.EditField
        xminLabel              matlab.ui.control.Label
        xmaxLabel              matlab.ui.control.Label
        yminLabel              matlab.ui.control.Label
        ymaxLabel              matlab.ui.control.Label
        zminLabel              matlab.ui.control.Label
        zmaxLabel              matlab.ui.control.Label
    end

    % (c) by Prof. Peter Felfer Group @FAU Erlangen-Nürnberg
    properties (Access = private)
        ax % Description
        xGlobalLim % max and min values of data
        yGlobalLim
        zGlobalLim
    end

    methods (Access = private)
    
        function update(app)
            if app.xenableCheckBox.Value && app.enableCheckBox.Value
                app.ax.XLim = [app.XSlider.Value - app.XspanEditField.Value/2, app.XSlider.Value + app.XspanEditField.Value/2];
            else
                app.ax.XLim = app.xGlobalLim;
            end
            
            if app.yenableCheckBox.Value && app.enableCheckBox.Value
                app.ax.YLim = [app.YSlider.Value - app.YspanEditField.Value/2, app.YSlider.Value + app.YspanEditField.Value/2];
            else
                app.ax.YLim = app.yGlobalLim;
            end
            
            if app.zenableCheckBox.Value && app.enableCheckBox.Value
                app.ax.ZLim = [app.ZSlider.Value - app.ZspanEditField.Value/2, app.ZSlider.Value + app.ZspanEditField.Value/2];
            else
                app.ax.ZLim = app.zGlobalLim;
            end
            
            app.updateLabels;
        end
        
        function updateLabels(app)
            xLim = app.ax.XLim;
            app.xMinLabel.Text = num2str(xLim(1));
            app.xMaxLabel.Text = num2str(xLim(2));
            
            yLim = app.ax.YLim;
            app.yMinLabel.Text = num2str(yLim(1));
            app.yMaxLabel.Text = num2str(yLim(2));
            
            zLim = app.ax.ZLim;
            app.zMinLabel.Text = num2str(zLim(1));
            app.zMaxLabel.Text = num2str(zLim(2));
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: gcaButton
        function gcaButtonPushed(app, event)
            app.ax = gca;
            
            % get max and min values of data in the axis and adjust sliders
            plots = app.ax.Children;
            for pl = 1:length(plots)
                x = plots(pl).XData;
                app.xGlobalLim = [min(x), max(x)];
                app.XSlider.Limits = app.xGlobalLim;
                app.XSlider.Value = mean(app.ax.XLim);
                
                y = plots(pl).YData;
                app.yGlobalLim = [min(y), max(y)];
                app.YSlider.Limits = app.yGlobalLim;
                app.YSlider.Value = mean(app.ax.YLim);
                
                z = plots(pl).ZData;
                app.zGlobalLim = [min(z), max(z)];
                app.ZSlider.Limits = app.zGlobalLim;
                app.ZSlider.Value = mean(app.ax.ZLim);
            end
                
            
            app.updateLabels;
            
            
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            if app.xenableCheckBox.Value && app.enableCheckBox.Value
                app.ax.XLim = [event.Value - app.XspanEditField.Value/2, event.Value + app.XspanEditField.Value/2];
            else
                app.ax.XLim = app.xGlobalLim;
            end
            
            app.updateLabels;
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            if app.yenableCheckBox.Value && app.enableCheckBox.Value
                app.ax.YLim = [event.Value - app.YspanEditField.Value/2, event.Value + app.YspanEditField.Value/2];
            else
                app.ax.YLim = app.yGlobalLim;
            end
            
            app.updateLabels;
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            if app.zenableCheckBox.Value && app.enableCheckBox.Value
                app.ax.ZLim = [event.Value - app.ZspanEditField.Value/2, event.Value + app.ZspanEditField.Value/2];
            else
                app.ax.ZLim = app.zGlobalLim;
            end
            
            app.updateLabels;
        end

        % Value changed function: enableCheckBox
        function enableCheckBoxValueChanged(app, event)
            app.update;
        end

        % Value changed function: XspanEditField
        function XspanEditFieldValueChanged(app, event)
            app.update;
        end

        % Value changed function: xenableCheckBox
        function xenableCheckBoxValueChanged(app, event)
            app.update;
        end

        % Value changed function: YspanEditField
        function YspanEditFieldValueChanged(app, event)
            app.update;
        end

        % Value changed function: yenableCheckBox
        function yenableCheckBoxValueChanged(app, event)
            app.update;
        end

        % Value changed function: ZspanEditField
        function ZspanEditFieldValueChanged(app, event)
            app.update;
        end

        % Value changed function: zenableCheckBox
        function zenableCheckBoxValueChanged(app, event)
            app.update;
        end

        % Button pushed function: ROIfromcropButton
        function ROIfromcropButtonPushed(app, event)
            loc = [sum(app.ax.XLim)/2, sum(app.ax.YLim)/2, sum(app.ax.ZLim)/2];
            span = abs([diff(app.ax.XLim), diff(app.ax.YLim), diff(app.ax.ZLim)]);
            roi = roiCreateBox(span,loc,app.ax);
            roi.DisplayName = app.ROInameEditField.Value;
            roi.FaceColor = [1 0 0];
            assignin("base",app.ROInameEditField.Value,roi);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.croppingUIFigure = uifigure('Visible', 'off');
            app.croppingUIFigure.Name = 'cropping';
            app.gcaButton = uibutton(app.croppingUIFigure, 'push');
            app.gcaButton.ButtonPushedFcn = createCallbackFcn(app, @gcaButtonPushed, true);
            app.gcaButton.Text = 'gca';
            app.XSliderLabel = uilabel(app.croppingUIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.croppingUIFigure);
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.croppingUIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.croppingUIFigure);
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.croppingUIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.croppingUIFigure);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.XspanEditFieldLabel = uilabel(app.croppingUIFigure);
            app.XspanEditFieldLabel.Text = 'X span';
            app.XspanEditField = uieditfield(app.croppingUIFigure, 'numeric');
            app.XspanEditField.ValueChangedFcn = createCallbackFcn(app, @XspanEditFieldValueChanged, true);
            app.XspanEditField.Value = 5;
            app.YspanEditFieldLabel = uilabel(app.croppingUIFigure);
            app.YspanEditFieldLabel.Text = 'Y span';
            app.YspanEditField = uieditfield(app.croppingUIFigure, 'numeric');
            app.YspanEditField.ValueChangedFcn = createCallbackFcn(app, @YspanEditFieldValueChanged, true);
            app.YspanEditField.Value = 5;
            app.ZspanEditFieldLabel = uilabel(app.croppingUIFigure);
            app.ZspanEditFieldLabel.Text = 'Z span';
            app.ZspanEditField = uieditfield(app.croppingUIFigure, 'numeric');
            app.ZspanEditField.ValueChangedFcn = createCallbackFcn(app, @ZspanEditFieldValueChanged, true);
            app.ZspanEditField.Value = 5;
            app.xenableCheckBox = uicheckbox(app.croppingUIFigure);
            app.xenableCheckBox.ValueChangedFcn = createCallbackFcn(app, @xenableCheckBoxValueChanged, true);
            app.xenableCheckBox.Text = '   x enable';
            app.yenableCheckBox = uicheckbox(app.croppingUIFigure);
            app.yenableCheckBox.ValueChangedFcn = createCallbackFcn(app, @yenableCheckBoxValueChanged, true);
            app.yenableCheckBox.Text = '   y enable';
            app.zenableCheckBox = uicheckbox(app.croppingUIFigure);
            app.zenableCheckBox.ValueChangedFcn = createCallbackFcn(app, @zenableCheckBoxValueChanged, true);
            app.zenableCheckBox.Text = '   z enable';
            app.enableCheckBox = uicheckbox(app.croppingUIFigure);
            app.enableCheckBox.ValueChangedFcn = createCallbackFcn(app, @enableCheckBoxValueChanged, true);
            app.enableCheckBox.Text = '     enable';
            app.enableCheckBox.Value = true;
            app.xMinLabel = uilabel(app.croppingUIFigure);
            app.xMinLabel.Text = 'xmin';
            app.xMaxLabel = uilabel(app.croppingUIFigure);
            app.xMaxLabel.Text = 'xmax';
            app.yMinLabel = uilabel(app.croppingUIFigure);
            app.yMinLabel.Text = 'ymin';
            app.yMaxLabel = uilabel(app.croppingUIFigure);
            app.yMaxLabel.Text = 'ymax';
            app.zMaxLabel = uilabel(app.croppingUIFigure);
            app.zMaxLabel.Text = 'zmax';
            app.zMinLabel = uilabel(app.croppingUIFigure);
            app.zMinLabel.Text = 'zmin';
            app.ROIfromcropButton = uibutton(app.croppingUIFigure, 'push');
            app.ROIfromcropButton.ButtonPushedFcn = createCallbackFcn(app, @ROIfromcropButtonPushed, true);
            app.ROIfromcropButton.Text = 'ROI from crop';
            app.ROInameEditFieldLabel = uilabel(app.croppingUIFigure);
            app.ROInameEditFieldLabel.Text = 'ROI name';
            app.ROInameEditField = uieditfield(app.croppingUIFigure, 'text');
            app.ROInameEditField.Value = 'roi_crop';
            app.xminLabel = uilabel(app.croppingUIFigure);
            app.xminLabel.Text = 'xmin';
            app.xmaxLabel = uilabel(app.croppingUIFigure);
            app.xmaxLabel.Text = 'xmax';
            app.yminLabel = uilabel(app.croppingUIFigure);
            app.yminLabel.Text = 'ymin';
            app.ymaxLabel = uilabel(app.croppingUIFigure);
            app.ymaxLabel.Text = 'ymax';
            app.zminLabel = uilabel(app.croppingUIFigure);
            app.zminLabel.Text = 'zmin';
            app.zmaxLabel = uilabel(app.croppingUIFigure);
            app.zmaxLabel.Text = 'zmax';
            % Show the figure after all components are created
            app.croppingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cropping

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.croppingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.croppingUIFigure)
        end
    end
end