classdef ionFind < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ionFindUIFigure   matlab.ui.Figure
        ionsListBoxLabel  matlab.ui.control.Label
        ionsListBox       matlab.ui.control.ListBox
        OKButton          matlab.ui.control.Button
        CancelButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        ionTable % table containing ions and m/c values
        isotopeTable % table of isotopic abundances for ions
        colorScheme % table with ion - color relationships
        spec % mass spectrum plot handle
        peakHeight % height of the selected peak
        peakLoc % location of selected peak
        ionPlot % plothandle of the ion plot
        dist % distance between peak and ion mc
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, spec, ionTable, isotopeTable, colorScheme, searchRange)
            % allocate input variables
            app.colorScheme = colorScheme;
            app.spec = spec;
            app.isotopeTable = isotopeTable;
          
            % select peak in mass spectrum
            axes(spec.Parent);
            %rect = getrect(spec.Parent);
            [x, ~] = ginput(2);
            
            if ~exist('searchRange','var')
                searchRange = 2; %Da
            end
            x = sort(x);
            %lowerLim = rect(1);
            %upperLim = rect(1) + rect(3);
            lowerLim = x(1);
            upperLim = x(2);
            
            xData = spec.XData(spec.XData > lowerLim & spec.XData < upperLim);
            yData = spec.YData(spec.XData > lowerLim & spec.XData < upperLim);
            
            peakIdx = find(yData == max(yData));
            peakIdx = peakIdx(1); % in case of noisy data, the maximum may occur multiple times
            app.peakLoc = xData(peakIdx);
            app.peakHeight = yData(peakIdx);
            
            % only consider ions within the search range
            app.ionTable = ionTable(ionTable.mc > app.peakLoc - searchRange & ionTable.mc < app.peakLoc + searchRange,:);
            app.dist = app.ionTable.mc - app.peakLoc;
            
            
            % populate listbox
            % first deviation, then ion name
            distStr = arrayfun(@(x) num2str(x,'%+4.2f'), app.dist, 'UniformOutput',false);
            ionNameStr = cellstr(app.ionTable.ionIsotopic);
            space = repmat({'   '},size(distStr));
            
            app.ionsListBox.Items = strcat(distStr, space, ionNameStr);
            
            % default selection is the ion that is closest to the peak
            default = find(abs(app.dist) == min(abs(app.dist)));
            default = default(1);
            app.ionsListBox.Value = app.ionsListBox.Items(default);
            
            minAbundance = 0;
            sumMargin = 0.1;
            maxHeight = [app.peakLoc, app.peakHeight];
            
            [ion, chargeState] = ionConvertName(app.ionsListBox.Value(8:end));
            ion = string(ionConvertName(ion.element));
            
            % plot ion in mass spectrum
            app.ionPlot = ionAdd(app.spec,...
                ion,...
                chargeState,...
                app.isotopeTable,...
                app.colorScheme,...
                sumMargin,...
                minAbundance,...
                maxHeight);
            
            % add additional information in UserData field
            
        end

        % Value changed function: ionsListBox
        function ionsListBoxValueChanged(app, event)
            delete(app.ionPlot);
            
            minAbundance = 0;
            sumMargin = 0.1;
            maxHeight = [app.peakLoc, app.peakHeight];
            
            [ion, chargeState] = ionConvertName(app.ionsListBox.Value(8:end));
            ion = string(ionConvertName(ion.element));
            
            % plot ion in mass spectrum
            app.ionPlot = ionAdd(app.spec,...
                ion,...
                chargeState,...
                app.isotopeTable,...
                app.colorScheme,...
                sumMargin,...
                minAbundance,...
                maxHeight);
            
            % add additional information in UserData field
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            delete(app);
        end

        % Close request function: ionFindUIFigure
        function ionFindUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app.ionPlot);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ionFindUIFigure = uifigure('Visible', 'off');
            app.ionFindUIFigure.Name = 'ion Find';
            app.ionFindUIFigure.CloseRequestFcn = createCallbackFcn(app, @ionFindUIFigureCloseRequest, true);
            app.ionsListBoxLabel = uilabel(app.ionFindUIFigure);
            app.ionsListBoxLabel.Text = 'ions';
            app.ionsListBox = uilistbox(app.ionFindUIFigure);
            app.ionsListBox.Items = {};
            app.ionsListBox.ValueChangedFcn = createCallbackFcn(app, @ionsListBoxValueChanged, true);
            app.ionsListBox.Value = {};
            app.OKButton = uibutton(app.ionFindUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.ionFindUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ionFindUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ionFind(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ionFindUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ionFindUIFigure)
        end
    end
end