classdef reconExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        reconxplorerUIFigure       matlab.ui.Figure
        kfSliderLabel              matlab.ui.control.Label
        kfSlider                   matlab.ui.control.Slider
        ICFSliderLabel             matlab.ui.control.Label
        ICFSlider                  matlab.ui.control.Slider
        executeAfterTextAreaLabel  matlab.ui.control.Label
        executeAfterTextArea       matlab.ui.control.TextArea
        reconTimeText              matlab.ui.control.Label
        kfEditField                matlab.ui.control.NumericEditField
        ICFEditField               matlab.ui.control.NumericEditField
        FDMaxes                    matlab.ui.control.UIAxes
        projectioncenterButton     matlab.ui.control.Button
    end


    properties (Access = private)
        detX % detector x data
        detY % detector y data
        voltage % hit voltage data
        isDisplay % which hits to visualize
        flightPathLength % flight path length of instrument
        atomicVol % atomic volume of hit. can be scalar (average) or per hit (vector)
        detEff % detection efficiency of instrument
        evaporationField % evaporation field of specimen
        visAxis % axis of visulisation
        visObject % scatterplot used for visualisation
        visFigure % figure for visualisation
        downSample % number of Atoms used for reconstruction during slider movement
        ang % polar coordinate angle of a hit
        rad % polar coordinate radius of a hit
        Adet % effective detector area
        omega % cumulative atomic volume
        projectionCenter % projection center of reconstruction
        projectionCenterPlot % handle of pc plot
    end

    methods (Access = private)
        
        function [x, y, z] = APTrecon(app)
            tic;
            % radius evolution from voltage curve (in nm)
            Rspec = app.voltage /(app.kfSlider.Value * app.evaporationField);

            
            %% calcualte x and y coordinates
            % launch angle relaive to specimen axis
            thetaP = atan(app.rad / app.flightPathLength);
            theta = thetaP + asin((app.ICFSlider.Value - 1) * sin(thetaP));
            
            % distance from axis and z shift of each hit
            [zP, d] = pol2cart(theta, Rspec); % nm
            
            % x and y coordinates from the angle on the detector and the distance to
            % the specimen axis.
            [x, y] = pol2cart(app.ang, d); % nm
            
            %% calculate z coordinate
            % the z shift with respect to the top of the cap is Rspec - zP
            zP = Rspec - zP;
            
            %dz = app.flightPathLength^2 * app.kfSlider.Value^2 * app.evaporationField^2 / (app.detEff * app.Adet * app.ICFSlider.Value^2) * app.voltage.^-2 .* omega; % nm^3 * mm^2 * V^2/nm^2 / (mm^2 * V^2)
            cumZ = app.flightPathLength^2 * app.kfSlider.Value^2 * app.evaporationField^2 / (app.detEff * app.Adet * app.ICFSlider.Value^2) * app.voltage.^-2 .* app.omega; % nm^3 * mm^2 * V^2/nm^2 / (mm^2 * V^2)
            % wide angle correction
            %cumZ = cumsum(double(dz));
            z = cumZ + zP;
            
            app.reconTimeText.Text = ['recon time: ' num2str(toc) ' s'];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, detX, detY, voltage, isDisplay, flightPathLength, atomicVol, detEff, evaporationField)
            %CONSTANTS
            FDM_RES = 128; %resolution of the field desorption map in pixels
            
            
            % variable setup
            numAtoms = length(detX);
            
            app.detX = double(detX);
            app.detY = double(detY);
            app.voltage = voltage;
            app.isDisplay = isDisplay;
            app.flightPathLength = flightPathLength;
            
            % atomicVol can be scalar or vector
            if isscalar(atomicVol)
                app.atomicVol = ones(numAtoms,1) * atomicVol;
            else
                app.atomicVol = atomicVol;
            end
            
            app.detEff = detEff;
            app.evaporationField = evaporationField;


            % recon setup
            % detector coordinates in polar form
            [app.ang, app.rad] = cart2pol(app.detX, app.detY);
            
            % calcualting effective detector area:
            [~, app.Adet] = convhull(app.detX,app.detY);
            
            % accumulative part of z
            app.omega = 1 ./ app.atomicVol; % atmic volume in nm^3
            app.omega = cumsum(double(app.omega));            
            
            % perform actual recon
            [x, y, z] = APTrecon(app);


            % visualisation setup
            app.visFigure = figure('Name','live recon');
            app.visAxis = axes;
            app.visObject = scatter3(app.visAxis, x(app.isDisplay), y(app.isDisplay) ,z(app.isDisplay) ,'b','.');
            axis equal;
            rotate3d on;
            
            set(app.visAxis,'Box','On');
            set(app.visAxis,'BoxStyle','full');
            set(app.visAxis,'XColor',[1 0 0]);
            set(app.visAxis,'YColor',[0 1 0]);
            set(app.visAxis,'ZColor',[0 0 1]);
            
            set(app.visAxis,'ZDir','reverse');
            
            set(app.visFigure,'Color',[1 1 1]);
            
            
            % calculate FDM for center placing
            %% calculating bin centers for FDM
            
            mi = min(min(app.detX),min(app.detY));
            mx = max(max(app.detX),max(app.detY));
            
            mi = mi * 1.1;
            mx = mx * 1.1;
            
            ctr{1} = linspace(mi,mx,FDM_RES);
            ctr{2} = ctr{1};
            FDM = hist3([app.detX, app.detY],ctr);
            imagesc(app.FDMaxes,FDM,'XData',[mi mx],'YData',[mi mx]);
            axis equal;
            
            %set and display projection center
            app.projectionCenter = [0, 0];
            %app.projectionCenterPlot = plot(app.projectionCenter(1),app.projectionCenter(2),'Parent',app.FDMaxes);
            
        end

        % Value changed function: kfSlider
        function kfSliderValueChanged(app, event)
            app.kfEditField.Value = app.kfSlider.Value;
            [x, y, z] = APTrecon(app);
            app.visObject.XData = x(app.isDisplay);
            app.visObject.YData = y(app.isDisplay);
            app.visObject.ZData = z(app.isDisplay);
        end

        % Value changed function: ICFSlider
        function ICFSliderValueChanged(app, event)
            app.ICFEditField.Value = app.ICFSlider.Value;
            [x, y, z] = APTrecon(app);
            app.visObject.XData = x(app.isDisplay);
            app.visObject.YData = y(app.isDisplay);
            app.visObject.ZData = z(app.isDisplay);
        end

        % Value changed function: kfEditField
        function kfEditFieldValueChanged(app, event)
            app.kfSlider.Value = app.kfEditField.Value;
            [x, y, z] = APTrecon(app);
            app.visObject.XData = x(app.isDisplay);
            app.visObject.YData = y(app.isDisplay);
            app.visObject.ZData = z(app.isDisplay);
        end

        % Value changed function: ICFEditField
        function ICFEditFieldValueChanged(app, event)
            app.ICFSlider.Value = app.ICFEditField.Value;
            [x, y, z] = APTrecon(app);
            app.visObject.XData = x(app.isDisplay);
            app.visObject.YData = y(app.isDisplay);
            app.visObject.ZData = z(app.isDisplay);
        end

        % Button pushed function: projectioncenterButton
        function projectioncenterButtonPushed(app, event)
            set(0, 'CurrentFigure', app.reconxplorerUIFigure);
            %set(app.reconxplorerUIFigure,'CurrentAxis',app.FDMaxes);
            [x,y] = ginput(1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.reconxplorerUIFigure = uifigure;
            app.reconxplorerUIFigure.Name = 'recon xplorer';
            app.kfSliderLabel = uilabel(app.reconxplorerUIFigure);
            app.kfSliderLabel.Text = 'kf';
            app.kfSlider = uislider(app.reconxplorerUIFigure);
            app.kfSlider.Limits = [2 7];
            app.kfSlider.ValueChangedFcn = createCallbackFcn(app, @kfSliderValueChanged, true);
            app.kfSlider.Value = 4;
            app.ICFSliderLabel = uilabel(app.reconxplorerUIFigure);
            app.ICFSliderLabel.Text = 'ICF';
            app.ICFSlider = uislider(app.reconxplorerUIFigure);
            app.ICFSlider.Limits = [1 3];
            app.ICFSlider.ValueChangedFcn = createCallbackFcn(app, @ICFSliderValueChanged, true);
            app.ICFSlider.Value = 1.5;
            app.executeAfterTextAreaLabel = uilabel(app.reconxplorerUIFigure);
            app.executeAfterTextAreaLabel.Text = 'executeAfter';
            app.executeAfterTextArea = uitextarea(app.reconxplorerUIFigure);
            app.reconTimeText = uilabel(app.reconxplorerUIFigure);
            app.kfEditField = uieditfield(app.reconxplorerUIFigure, 'numeric');
            app.kfEditField.ValueChangedFcn = createCallbackFcn(app, @kfEditFieldValueChanged, true);
            app.ICFEditField = uieditfield(app.reconxplorerUIFigure, 'numeric');
            app.ICFEditField.ValueChangedFcn = createCallbackFcn(app, @ICFEditFieldValueChanged, true);
            app.FDMaxes = uiaxes(app.reconxplorerUIFigure);
            title(app.FDMaxes, 'FDM')
            xlabel(app.FDMaxes, 'x det')
            ylabel(app.FDMaxes, 'y det')
            app.projectioncenterButton = uibutton(app.reconxplorerUIFigure, 'push');
            app.projectioncenterButton.ButtonPushedFcn = createCallbackFcn(app, @projectioncenterButtonPushed, true);
            app.projectioncenterButton.Text = 'projection center';
        end
    end

    methods (Access = public)

        % Construct app
        function app = reconExplorer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.reconxplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.reconxplorerUIFigure)
        end
    end
end